classdef path_create_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        LSY2EditField       matlab.ui.control.NumericEditField
        Y2EditFieldLabel    matlab.ui.control.Label
        LSX2EditField       matlab.ui.control.NumericEditField
        X2EditFieldLabel    matlab.ui.control.Label
        LSY1EditField       matlab.ui.control.NumericEditField
        Y1EditFieldLabel    matlab.ui.control.Label
        LSX1EditField       matlab.ui.control.NumericEditField
        X1EditFieldLabel    matlab.ui.control.Label
        CreateLS            matlab.ui.control.Button
        LineSegmentLabel_2  matlab.ui.control.Label
        CreatePoint         matlab.ui.control.Button
        PointYEditField     matlab.ui.control.NumericEditField
        YEditFieldLabel     matlab.ui.control.Label
        PointXEditField     matlab.ui.control.NumericEditField
        XEditFieldLabel     matlab.ui.control.Label
        PointLabel          matlab.ui.control.Label
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Frame 
    end
    
    properties (Access = public)
    end
    
    methods (Access = private)
        
        function update_point(~, ax, point)
            point.Pos = ax.CurrentPoint(1, 1:2);
        end
        
        function redraw_poly(~, axh, varargin)
            % pasted from draggableDemo

            % Deleting the previous polygon
            delete(getappdata(axh, "ls"));
            
            % Retrieving the vertex vector and corresponding xdata and ydata
            points = getappdata(axh, "points");
            xdata = cell2mat(get(points, "xdata"));
            ydata = cell2mat(get(points, "ydata"));
            
            % Plotting the new polygon and saving its handle as application data
            ls = plot(axh, [xdata.' xdata(1)],[ydata.' ydata(1)], "Color", "k");
            setappdata(axh, "ls", ls);
            
            % Putting the vertices on top of the polygon so that they are easier to drag (or
            % else, the polygone line get in the way)
             set(axh, "Children", [points ls]);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Frame = Frame;
            
            ax = app.UIAxes();
            axis(ax, "equal");
            set(ax, "DataAspectRatio", [1 1 1], "Xlim", [-2 2], "YLim", [-2 2]);
            hold(ax, "on")
        end

        % Button pushed function: CreatePoint
        function CreatePointPushed(app, event)
            x = app.PointXEditField;
            y = app.PointYEditField;
            p = Point(app.Frame, x, y);

            plotp = plot(app.UIAxes, x, y);
            draggable(plotp, @(g)update_point(app, app.UIAxes, p));

            % for visible vertices
            set(plotp, "Marker", "o", "MarkerSize", 10, "MarkerFaceColor", "b", ...
            "MarkerEdgeColor", "none");
            
%             setappdata(app.UIAxes(), "points", points);

        end

        % Button pushed function: CreateLS
        function CreateLSButtonPushed(app, event)
            ax = app.UIAxes;
            
            x1 = app.LSX1EditField;
            y1 = app.LSY1EditField;
            x2 = app.LSX2EditField;
            y2 = app.LSY2EditField;
            l = LineSegment(app.Frame, x1, y1, x2, y2);

            p1 = plot(ax, x1, y1);
            p2 = plot(ax, x2, y2);
            points = [p1 p2];
            
            % draggable line segment
%             plotline = line(ax, [x1 x2], [y1 y2]);
%             set(magline, "Color", "m", "LineWidth", 2);
%             draggable(magline);

            % for visible vertices
            set(points, "Marker", "o", "MarkerSize", 10, "MarkerFaceColor", "b", ...
            "MarkerEdgeColor", "none");

            setappdata(ax, "points", points);
            setappdata(ax, "ls", []);

            draggable(p1, @(g)redraw_poly(app, ax, g));
            draggable(p2, @(g)redraw_poly(app, ax, g));

            redraw_poly(app, ax);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Frame')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PointLabel = uilabel(app.UIFigure);
            app.PointLabel.Text = 'Point';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.PointXEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.PointYEditField = uieditfield(app.UIFigure, 'numeric');
            app.CreatePoint = uibutton(app.UIFigure, 'push');
            app.CreatePoint.ButtonPushedFcn = createCallbackFcn(app, @CreatePointPushed, true);
            app.CreatePoint.Text = 'Create';
            app.LineSegmentLabel_2 = uilabel(app.UIFigure);
            app.LineSegmentLabel_2.Text = 'Line Segment';
            app.CreateLS = uibutton(app.UIFigure, 'push');
            app.CreateLS.ButtonPushedFcn = createCallbackFcn(app, @CreateLSButtonPushed, true);
            app.CreateLS.Text = 'Create';
            app.X1EditFieldLabel = uilabel(app.UIFigure);
            app.X1EditFieldLabel.Text = 'X1';
            app.LSX1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Y1EditFieldLabel = uilabel(app.UIFigure);
            app.Y1EditFieldLabel.Text = 'Y1';
            app.LSY1EditField = uieditfield(app.UIFigure, 'numeric');
            app.X2EditFieldLabel = uilabel(app.UIFigure);
            app.X2EditFieldLabel.Text = 'X2';
            app.LSX2EditField = uieditfield(app.UIFigure, 'numeric');
            app.LSX2EditField.Value = 1;
            app.Y2EditFieldLabel = uilabel(app.UIFigure);
            app.Y2EditFieldLabel.Text = 'Y2';
            app.LSY2EditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = path_create_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end